classdef DobotTicTacToeGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        JogPanel                       matlab.ui.container.Panel
        xSliderLabel                   matlab.ui.control.Label
        xSlider                        matlab.ui.control.Slider
        ySliderLabel                   matlab.ui.control.Label
        ySlider                        matlab.ui.control.Slider
        zSliderLabel                   matlab.ui.control.Label
        zSlider                        matlab.ui.control.Slider
        EnablejoggingCheckBox          matlab.ui.control.CheckBox
        TeachPanel                     matlab.ui.container.Panel
        ShoulderSliderLabel            matlab.ui.control.Label
        ShoulderSlider                 matlab.ui.control.Slider
        ElbowSliderLabel               matlab.ui.control.Label
        ElbowSlider                    matlab.ui.control.Slider
        EndSliderLabel                 matlab.ui.control.Label
        EndSlider                      matlab.ui.control.Slider
        BaseSliderLabel                matlab.ui.control.Label
        BaseSlider                     matlab.ui.control.Slider
        SuctioncupCheckBox             matlab.ui.control.CheckBox
        DobotTicTacToeLabel            matlab.ui.control.Label
        SafetyPanel                    matlab.ui.container.Panel
        SafetysymbolxcoordinateSlider  matlab.ui.control.Slider
        SafetysymbolxcoordinateSliderLabel  matlab.ui.control.Label
        SimulatesafetysymbolButton     matlab.ui.control.StateButton
        DistancethresholdEditFieldLabel  matlab.ui.control.Label
        DistancethresholdEditField     matlab.ui.control.NumericEditField
        GamePanel                      matlab.ui.container.Panel
        StartgameButton                matlab.ui.control.Button
        ResumeButton                   matlab.ui.control.Button
        ESTOPButton                    matlab.ui.control.StateButton
        MovePanel                      matlab.ui.container.Panel
        Button_1                       matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        Button_4                       matlab.ui.control.Button
        Button_5                       matlab.ui.control.Button
        Button_6                       matlab.ui.control.Button
        Button_7                       matlab.ui.control.Button
        Button_8                       matlab.ui.control.Button
        Button_9                       matlab.ui.control.Button
        LightcurtainButton             matlab.ui.control.StateButton
        UserealrobotCheckBox           matlab.ui.control.CheckBox
        WinnerLabel                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        game;
        resume = false;
        safetysymbolh;
    end
    
    methods (Access = private)
        
        function value = snapSlider(~, slider)
            value = 2*round(slider.Value/2,1);
            slider.Value = value;
        end
        
        function v = readJogSliders(app)
            % read jog sliders and snap to increments
            v(1) = snapSlider(app, app.xSlider);
            v(2) = snapSlider(app, app.ySlider);
            v(3) = snapSlider(app, app.zSlider);
        end
        
        function q = readTeachSliders(app)
            q = zeros(1,4);
            q(1) = app.BaseSlider.Value;
            q(2) = app.ShoulderSlider.Value;
            q(3) = app.ElbowSlider.Value;
            q(4) = app.EndSlider.Value;
        end
        
        function setTeachSliders(app,q)
            app.BaseSlider.Value = q(1);
            app.ShoulderSlider.Value = q(2);
            app.ElbowSlider.Value = q(3);
            app.EndSlider.Value = q(4);
        end
        
        function displayWinner(app,winner)
            if winner == 1
               app.WinnerLabel.Text = "You won!";
            elseif winner == 2
                app.WinnerLabel.Text = "Robot won!";
            elseif winner == 0
                app.WinnerLabel.Text = "Draw!";
            end
        end
        
        function clearWinner(app)
            app.WinnerLabel.Text = "";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try % try to start with real robot preference, otherwise start simulated
                app.game = DobotTicTacToe(app.UserealrobotCheckBox.Value);
                if app.UserealrobotCheckBox.Value
                    app.game.dobot.PublishTargetJoint(app.game.dobot.qn);
                end
            catch
                app.game = DobotTicTacToe(false);
                app.UserealrobotCheckBox.Value = false;
            end
            
            app.game.PopulateEnvironment();
            
            % set teach limits
            app.BaseSlider.Limits = app.game.dobot.qlimReal(1,:);
            app.ShoulderSlider.Limits = app.game.dobot.qlimReal(2,:);
            app.ElbowSlider.Limits = app.game.dobot.qlimReal(3,:);
            app.EndSlider.Limits = app.game.dobot.qlimReal(4,:);
            
            % set teach values
            setTeachSliders(app,app.game.dobot.qn);
        end

        % Value changed function: ESTOPButton
        function ESTOPButtonValueChanged(app, event)
            value = app.ESTOPButton.Value;
            app.EnablejoggingCheckBox.Value = 0; % disable jogging
            % state button
            % if pressed, stop, store which player
            if value
                disp("EStop Pressed")
                app.game.dobot.EStopRobot();
            else
                disp("EStop Released")
                app.game.dobot.ReleaseEStop();
            end
            % if unpressed, allow resuming
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            if ~app.ESTOPButton.Value && ~app.LightcurtainButton.Value 
                % estop is not pressed and light curtain is not triggered
                disp("Resuming")
                app.resume = true;
                app.StartgameButtonPushed();
            end
        end

        % Button pushed function: StartgameButton
        function StartgameButtonPushed(app, event)
            app.clearWinner();
            % run game function
            winner = app.game.PlayGame(1,app.resume);
            if winner >= 0
                % finished successfully
                app.resume = false;
            end
            app.displayWinner(winner)
        end

        % Value changed function: BaseSlider, ElbowSlider, 
        % EndSlider, ShoulderSlider
        function TeachSliderValueChanged(app, event)
            q = readTeachSliders(app);
            app.game.dobot.Animate(q);
            if app.UserealrobotCheckBox.Value
                app.game.dobot.PublishTargetJoint(q);
            end
        end

        % Value changed function: UserealrobotCheckBox
        function UserealrobotCheckBoxValueChanged(app, event)
            app.startupFcn(); % restart app with preferences
        end

        % Value changed function: SuctioncupCheckBox
        function SuctioncupCheckBoxValueChanged(app, event)
            value = app.SuctioncupCheckBox.Value;
            if app.UserealrobotCheckBox.Value
                app.game.dobot.PublishToolState(uint8(value));
            end
        end

        % Value changed function: EnablejoggingCheckBox
        function EnablejoggingCheckBoxValueChanged(app, event)
            fps = 10;
            maxSpeed = 0.02;
            q = app.game.dobot.GetPos();
            while app.EnablejoggingCheckBox.Value && ~app.ESTOPButton.Value
                tic;
                v = readJogSliders(app);
                q = app.game.manual(v,q,maxSpeed/fps);
                app.setTeachSliders(q);
                if app.UserealrobotCheckBox.Value
                    app.game.dobot.PublishTargetJoint(q);
                else
                    elapsed = toc;
                    if elapsed < 1/fps
                        pause(1/fps - elapsed);
                    end
                end
            end
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.game.move = [1,1];
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.game.move = [1,2];
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.game.move = [1,3];
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.game.move = [2,1];
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.game.move = [2,2];
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.game.move = [2,3];
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.game.move = [3,1];
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.game.move = [3,2];
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.game.move = [3,3];
        end

        % Value changed function: LightcurtainButton
        function LightcurtainButtonValueChanged(app, event)
            app.EnablejoggingCheckBox.Value = 0; % disable jogging

            % virtually press estop
            if app.LightcurtainButton.Value
                disp("Light curtain triggered")
                app.game.dobot.EStopRobot();
            else
                app.game.dobot.ReleaseEStop();
            end
            % if unpressed, allow resuming
        end

        % Value changed function: SimulatesafetysymbolButton
        function SimulatesafetysymbolButtonValueChanged(app, event)
            value = app.SimulatesafetysymbolButton.Value;
            % plot safety symbol
            q = app.game.dobot.GetPos();
            gain = 0.01;
            dt = 0.1;
            while app.SimulatesafetysymbolButton.Value
                % calculate wrench based on position of safety symbol
                symbolx = app.SafetysymbolxcoordinateSlider.Value;
                hold on;
                delete(app.safetysymbolh);
                app.safetysymbolh = surf([symbolx,symbolx;symbolx,symbolx],[-0.1,0.1;-0.1,0.1],[0.1,0.1;-0.1,-0.1],'CData',imread('Warning.png'),'FaceColor','texturemap');  
                endeffector = app.game.dobot.Fkine(q);
                xdistance = abs(symbolx - endeffector(1,4));
                if xdistance < app.DistancethresholdEditField.Value
                    wrench = [-gain*(1-xdistance) 0 0];
                else
                    wrench = [0 0 0];
                end
                q = app.game.dobot.RMRC(wrench,q,dt);
                app.game.dobot.Animate(q);
            end
            delete(app.safetysymbolh);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.JogPanel = uipanel(app.UIFigure);
            app.JogPanel.Title = 'Jog';
            app.xSliderLabel = uilabel(app.JogPanel);
            app.xSliderLabel.Text = 'x';
            app.xSlider = uislider(app.JogPanel);
            app.xSlider.Limits = [-1 1];
            app.ySliderLabel = uilabel(app.JogPanel);
            app.ySliderLabel.Text = 'y';
            app.ySlider = uislider(app.JogPanel);
            app.ySlider.Limits = [-1 1];
            app.zSliderLabel = uilabel(app.JogPanel);
            app.zSliderLabel.Text = 'z';
            app.zSlider = uislider(app.JogPanel);
            app.zSlider.Limits = [-1 1];
            app.EnablejoggingCheckBox = uicheckbox(app.JogPanel);
            app.EnablejoggingCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnablejoggingCheckBoxValueChanged, true);
            app.EnablejoggingCheckBox.Text = 'Enable jogging';
            app.TeachPanel = uipanel(app.UIFigure);
            app.TeachPanel.Title = 'Teach';
            app.ShoulderSliderLabel = uilabel(app.TeachPanel);
            app.ShoulderSliderLabel.Text = 'Shoulder';
            app.ShoulderSlider = uislider(app.TeachPanel);
            app.ShoulderSlider.Limits = [-1 1];
            app.ShoulderSlider.ValueChangedFcn = createCallbackFcn(app, @TeachSliderValueChanged, true);
            app.ElbowSliderLabel = uilabel(app.TeachPanel);
            app.ElbowSliderLabel.Text = 'Elbow';
            app.ElbowSlider = uislider(app.TeachPanel);
            app.ElbowSlider.Limits = [-1 1];
            app.ElbowSlider.ValueChangedFcn = createCallbackFcn(app, @TeachSliderValueChanged, true);
            app.EndSliderLabel = uilabel(app.TeachPanel);
            app.EndSliderLabel.Text = 'End';
            app.EndSlider = uislider(app.TeachPanel);
            app.EndSlider.Limits = [-1 1];
            app.EndSlider.ValueChangedFcn = createCallbackFcn(app, @TeachSliderValueChanged, true);
            app.BaseSliderLabel = uilabel(app.TeachPanel);
            app.BaseSliderLabel.Text = 'Base';
            app.BaseSlider = uislider(app.TeachPanel);
            app.BaseSlider.Limits = [-1 1];
            app.BaseSlider.ValueChangedFcn = createCallbackFcn(app, @TeachSliderValueChanged, true);
            app.SuctioncupCheckBox = uicheckbox(app.TeachPanel);
            app.SuctioncupCheckBox.ValueChangedFcn = createCallbackFcn(app, @SuctioncupCheckBoxValueChanged, true);
            app.SuctioncupCheckBox.Text = 'Suction cup';
            app.DobotTicTacToeLabel = uilabel(app.UIFigure);
            app.DobotTicTacToeLabel.Text = 'Dobot Tic Tac Toe';
            app.SafetyPanel = uipanel(app.UIFigure);
            app.SafetyPanel.Title = 'Safety';
            app.SafetysymbolxcoordinateSlider = uislider(app.SafetyPanel);
            app.SafetysymbolxcoordinateSlider.Limits = [0 0.5];
            app.SafetysymbolxcoordinateSlider.Value = 0.5;
            app.SafetysymbolxcoordinateSliderLabel = uilabel(app.SafetyPanel);
            app.SafetysymbolxcoordinateSliderLabel.Text = 'Safety symbol x coordinate';
            app.SimulatesafetysymbolButton = uibutton(app.SafetyPanel, 'state');
            app.SimulatesafetysymbolButton.ValueChangedFcn = createCallbackFcn(app, @SimulatesafetysymbolButtonValueChanged, true);
            app.SimulatesafetysymbolButton.Text = 'Simulate safety symbol';
            app.DistancethresholdEditFieldLabel = uilabel(app.SafetyPanel);
            app.DistancethresholdEditFieldLabel.Text = 'Distance threshold';
            app.DistancethresholdEditField = uieditfield(app.SafetyPanel, 'numeric');
            app.DistancethresholdEditField.Value = 0.2;
            app.GamePanel = uipanel(app.UIFigure);
            app.GamePanel.Title = 'Game';
            app.StartgameButton = uibutton(app.GamePanel, 'push');
            app.StartgameButton.ButtonPushedFcn = createCallbackFcn(app, @StartgameButtonPushed, true);
            app.StartgameButton.Text = 'Start game';
            app.ResumeButton = uibutton(app.GamePanel, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.ESTOPButton = uibutton(app.GamePanel, 'state');
            app.ESTOPButton.ValueChangedFcn = createCallbackFcn(app, @ESTOPButtonValueChanged, true);
            app.ESTOPButton.Text = 'ESTOP';
            app.MovePanel = uipanel(app.GamePanel);
            app.MovePanel.Title = 'Move';
            app.Button_1 = uibutton(app.MovePanel, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.MovePanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.MovePanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.MovePanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.MovePanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.MovePanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.MovePanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.MovePanel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.MovePanel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.LightcurtainButton = uibutton(app.GamePanel, 'state');
            app.LightcurtainButton.ValueChangedFcn = createCallbackFcn(app, @LightcurtainButtonValueChanged, true);
            app.LightcurtainButton.Text = 'Light curtain';
            app.UserealrobotCheckBox = uicheckbox(app.UIFigure);
            app.UserealrobotCheckBox.ValueChangedFcn = createCallbackFcn(app, @UserealrobotCheckBoxValueChanged, true);
            app.UserealrobotCheckBox.Text = 'Use real robot';
            app.WinnerLabel = uilabel(app.UIFigure);
            app.WinnerLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DobotTicTacToeGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end